classdef problemSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        InputforProblemTypesLabel  matlab.ui.control.Label
        OpenPlotFigureCheckBox     matlab.ui.control.CheckBox
        XInputEditField            matlab.ui.control.EditField
        XInputEditFieldLabel       matlab.ui.control.Label
        Equation3EditField         matlab.ui.control.EditField
        Equation3EditFieldLabel    matlab.ui.control.Label
        Equation2EditField         matlab.ui.control.EditField
        Equation2EditFieldLabel    matlab.ui.control.Label
        Equation1EditField         matlab.ui.control.EditField
        Equation1EditFieldLabel    matlab.ui.control.Label
        OrderEditField             matlab.ui.control.NumericEditField
        OrderEditFieldLabel        matlab.ui.control.Label
        TermEditField              matlab.ui.control.EditField
        TermEditFieldLabel         matlab.ui.control.Label
        UpperLimitEditField        matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel   matlab.ui.control.Label
        LowerLimitEditField        matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel   matlab.ui.control.Label
        StepCountEditField         matlab.ui.control.NumericEditField
        StepCountEditFieldLabel    matlab.ui.control.Label
        ExporttoWorkspaceCheckBox  matlab.ui.control.CheckBox
        InputHelpLabel             matlab.ui.control.Label
        InputEditField             matlab.ui.control.EditField
        InputEditFieldLabel        matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        CenterPanel                matlab.ui.container.Panel
        OutputTextArea             matlab.ui.control.TextArea
        OutputTextAreaLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        ProblemTypeDropDown        matlab.ui.control.DropDown
        ProblemTypeDropDownLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ExporttoWorkspaceCheckBox
        function ExporttoWorkspaceCheckBoxValueChanged(app, event)
            value = app.ExporttoWorkspaceCheckBox.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            syms x y z t a b c d e f g h k l m n o p q r s t u v w;
            eq = app.InputEditField.Value;
            if strcmp(app.ProblemTypeDropDown.Value, 'Simplify')
                app.OutputTextArea.Value = string(simplify(str2sym(eq),'Steps',app.StepCountEditField.Value,'All',true))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Solve For X')
                app.OutputTextArea.Value = string(solve(str2sym(eq),x))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Indefinite Integral')
                app.OutputTextArea.Value = string(int(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Definite Integral')
                app.OutputTextArea.Value = string(int(str2sym(eq),x,app.LowerLimitEditField.Value,app.UpperLimitEditField.Value))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Derivative')
                app.OutputTextArea.Value = string(diff(str2sym(eq),app.OrderEditField.Value))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Partial Derivative')
                app.OutputTextArea.Value = string(diff(str2sym(eq),app.TermEditField.Value))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Partial Fraction')
                app.OutputTextArea.Value = string(partfrac(str2sym(eq),x))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Factor')
                app.OutputTextArea.Value = string(factor(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Laplace')
                app.OutputTextArea.Value = string(laplace(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Inverse Laplace')
                app.OutputTextArea.Value = string(ilaplace(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Fourier')
                app.OutputTextArea.Value = string(fourier(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Inverse Fourier')
                app.OutputTextArea.Value = string(ifourier(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Inverse Fourier')
                app.OutputTextArea.Value = string(ifourier(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Fast Fourier Transform')
                app.OutputTextArea.Value = string(fft(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Inverse Fast Fourier Transform')
                app.OutputTextArea.Value = string(ifft(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Limit')
                app.OutputTextArea.Value = string(limit(str2sym(eq),app.TermEditField.Value,app.LowerLimitEditField.Value))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Roots')
                app.OutputTextArea.Value = string(roots(str2sym(eq)))
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'System of Equations')
                sys = solve(str2sym(app.Equation1EditField.Value),str2sym(app.Equation2EditField.Value),str2sym(app.Equation3EditField.Value))
                app.OutputTextArea.Value = string([sys.x sys.y sys.z])
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Plot')
                x = str2num(app.XInputEditField.Value);
                y = eval(eq);
                plot(app.UIAxes,x,y);
                if app.OpenPlotFigureCheckBox.Value == true
                    figure;
                    plot(x,y);
                end
                if app.ExporttoWorkspaceCheckBox.Value == true
                    assignin('base','Export',[x ; y])
                end
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Plot3D')
                x = str2num(app.XInputEditField.Value);
                y = eval(eq);
                plot3(app.UIAxes,real(y),imag(y),x);
                if app.OpenPlotFigureCheckBox.Value == true
                    figure;
                    plot3(real(y),imag(y),x);
                end
                if app.ExporttoWorkspaceCheckBox.Value == true
                    assignin('base','Export',[real(y) ; imag(y) ; x])
                end
            end
            if (app.ExporttoWorkspaceCheckBox.Value == true) && not(strcmp(app.ProblemTypeDropDown.Value, 'Plot') || strcmp(app.ProblemTypeDropDown.Value, 'Plot3D'))
                assignin('base','Export',app.OutputTextArea.Value)
            end
        end

        % Value changed function: ProblemTypeDropDown
        function ProblemTypeDropDownValueChanged(app, event)
            if strcmp(app.ProblemTypeDropDown.Value, 'Simplify')
                app.InputEditField.Value = 'sin(x)^2-cos(x)^2'
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Solve For X')
                app.InputEditField.Value = '2*x == 10'
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Indefinite Integral')
                app.InputEditField.Value = 'sin(5*x)'
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Plot')
                app.InputEditField.Value = 'sin(pi*x)./(pi*x)'
                app.XInputEditField.Value = '0:0.01:5'
            end
            if strcmp(app.ProblemTypeDropDown.Value, 'Plot3D')
                app.InputEditField.Value = 'exp(1*x).*exp(5*i*pi^3*x)'
                app.XInputEditField.Value = '0:0.01:5'
            end
        end

        % Callback function
        function StepCountEditFieldValueChanged(app, event)
            value = app.StepCountEditField.Value;
            
        end

        % Callback function
        function StepsSliderValueChanged(app, event)
            value = app.StepsSlider.Value;
            
        end

        % Drop down opening function: ProblemTypeDropDown
        function ProblemTypeDropDownOpening(app, event)
            
        end

        % Value changed function: InputEditField
        function InputEditFieldValueChanged(app, event)
            value = app.InputEditField.Value;
            
        end

        % Value changing function: InputEditField
        function InputEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: OutputTextArea
        function OutputTextAreaValueChanged(app, event)
            value = app.OutputTextArea.Value;
            
        end

        % Value changed function: StepCountEditField
        function StepCountEditFieldValueChanged2(app, event)
            value = app.StepCountEditField.Value;
            
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            value = app.UpperLimitEditField.Value;
            
        end

        % Value changed function: TermEditField
        function TermEditFieldValueChanged(app, event)
            value = app.TermEditField.Value;
            
        end

        % Value changing function: TermEditField
        function TermEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            value = app.OrderEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x', 227};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InputEditFieldLabel = uilabel(app.LeftPanel);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.LeftPanel, 'text');
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputEditFieldValueChanged, true);
            app.InputEditField.ValueChangingFcn = createCallbackFcn(app, @InputEditFieldValueChanging, true);
            app.InputEditField.Tooltip = {'Main input for most problems, must obey matlab syntax '};
            app.InputHelpLabel = uilabel(app.LeftPanel);
            app.InputHelpLabel.Tooltip = {'All problem types use x as the default variable '; 'Most problems won''t work without proper matlab syntax'; 'An example is automatically filled in for some types'; 'All letters except for i and j are available as variables (i and j are imaginary)'; ''};
            app.InputHelpLabel.Text = 'Input Help';
            app.ExporttoWorkspaceCheckBox = uicheckbox(app.LeftPanel);
            app.ExporttoWorkspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExporttoWorkspaceCheckBoxValueChanged, true);
            app.ExporttoWorkspaceCheckBox.Tooltip = {'Exports the output to your current workspace'};
            app.ExporttoWorkspaceCheckBox.Text = 'Export to Workspace';
            app.StepCountEditFieldLabel = uilabel(app.LeftPanel);
            app.StepCountEditFieldLabel.Text = 'Step Count';
            app.StepCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StepCountEditField.ValueChangedFcn = createCallbackFcn(app, @StepCountEditFieldValueChanged2, true);
            app.StepCountEditField.Tooltip = {'Changes how many steps are used in simplification, use more to simplify further'};
            app.StepCountEditField.Value = 1;
            app.LowerLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Tooltip = {'Lower limit of integration'; 'Also used for limit as (term) approaches (lower limit)'};
            app.UpperLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Tooltip = {'Upper limit of integration'};
            app.TermEditFieldLabel = uilabel(app.LeftPanel);
            app.TermEditFieldLabel.Text = 'Term';
            app.TermEditField = uieditfield(app.LeftPanel, 'text');
            app.TermEditField.ValueChangedFcn = createCallbackFcn(app, @TermEditFieldValueChanged, true);
            app.TermEditField.ValueChangingFcn = createCallbackFcn(app, @TermEditFieldValueChanging, true);
            app.TermEditField.Tooltip = {'Term to differentiate by'; 'Also used for limit as (term) approaches (lower limit)'};
            app.OrderEditFieldLabel = uilabel(app.LeftPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.OrderEditField.Tooltip = {'Order of differentiation'};
            app.OrderEditField.Value = 1;
            app.Equation1EditFieldLabel = uilabel(app.LeftPanel);
            app.Equation1EditFieldLabel.Text = 'Equation 1';
            app.Equation1EditField = uieditfield(app.LeftPanel, 'text');
            app.Equation1EditField.Tooltip = {'Use up to three variables for solving a system of equations'};
            app.Equation2EditFieldLabel = uilabel(app.LeftPanel);
            app.Equation2EditFieldLabel.Text = 'Equation 2';
            app.Equation2EditField = uieditfield(app.LeftPanel, 'text');
            app.Equation3EditFieldLabel = uilabel(app.LeftPanel);
            app.Equation3EditFieldLabel.Text = 'Equation 3';
            app.Equation3EditField = uieditfield(app.LeftPanel, 'text');
            app.XInputEditFieldLabel = uilabel(app.LeftPanel);
            app.XInputEditFieldLabel.Text = 'X Input';
            app.XInputEditField = uieditfield(app.LeftPanel, 'text');
            app.XInputEditField.Tooltip = {'Time vector for plotting'};
            app.OpenPlotFigureCheckBox = uicheckbox(app.LeftPanel);
            app.OpenPlotFigureCheckBox.Tooltip = {'Better graph using an external figure rather than built into the app window'};
            app.OpenPlotFigureCheckBox.Text = 'Open Plot Figure';
            app.InputforProblemTypesLabel = uilabel(app.LeftPanel);
            app.InputforProblemTypesLabel.Tooltip = {'Select a problem type from the right drop down menu'};
            app.InputforProblemTypesLabel.Text = 'Input for Problem Types ';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.OutputTextAreaLabel = uilabel(app.CenterPanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.CenterPanel);
            app.OutputTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputTextAreaValueChanged, true);
            app.OutputTextArea.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ProblemTypeDropDownLabel = uilabel(app.RightPanel);
            app.ProblemTypeDropDownLabel.Text = 'Problem Type';
            app.ProblemTypeDropDown = uidropdown(app.RightPanel);
            app.ProblemTypeDropDown.Items = {'Simplify', 'Solve For X', 'Indefinite Integral', 'Definite Integral', 'Derivative', 'Partial Derivative', 'Partial Fraction', 'Factor', 'Laplace', 'Inverse Laplace', 'Fourier', 'Inverse Fourier', 'Fast Fourier Transform', 'Inverse Fast Fourier Transform', 'Plot', 'Plot3D', 'Limit', 'Roots', 'System of Equations'};
            app.ProblemTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ProblemTypeDropDownOpening, true);
            app.ProblemTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemTypeDropDownValueChanged, true);
            app.ProblemTypeDropDown.Value = 'Simplify';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = problemSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end